classdef SAM_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OptionsTab                      matlab.ui.container.Tab
        FilePathEditFieldLabel          matlab.ui.control.Label
        EditField_Path                  matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        EditField_Filename              matlab.ui.control.EditField
        ExtensionxlscsvmatLabel         matlab.ui.control.Label
        EditField_Ext                   matlab.ui.control.EditField
        FileLabel                       matlab.ui.control.Label
        BrowseFilesButton               matlab.ui.control.Button
        WaterQualityLabel               matlab.ui.control.Label
        SelectWaterQualityOpenOcean15Coastal610Label  matlab.ui.control.Label
        SelectWaterQuality_ListBox      matlab.ui.control.ListBox
        MarineDeployment_ButtonGroup    matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        TimeZoneEditFieldLabel          matlab.ui.control.Label
        TimeZoneEditField               matlab.ui.control.NumericEditField
        DesiredOutputVariablesLabel     matlab.ui.control.Label
        CompileResultsButton            matlab.ui.control.Button
        OutputVariablesListBox          matlab.ui.control.ListBox
        CERESIrradianceDataFilePathLabel  matlab.ui.control.Label
        BrowseFoldersButton             matlab.ui.control.Button
        FilePathEditFieldLabel_2        matlab.ui.control.Label
        FilePathEditField               matlab.ui.control.EditField
        OutputFilePathLabel             matlab.ui.control.Label
        BrowseFoldersButton_2           matlab.ui.control.Button
        OutputFolderEditFieldLabel      matlab.ui.control.Label
        OutputFolderEditField           matlab.ui.control.EditField
        OutputFileTypematorcsvLabel     matlab.ui.control.Label
        OutputFileTypeDropDown          matlab.ui.control.DropDown
        SelectAllButton                 matlab.ui.control.Button
        AutomaticOutputVariablesTimeLabel  matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        CurrentdensityConstantCEditFieldLabel  matlab.ui.control.Label
        CurrentdensityConstantCEditField  matlab.ui.control.NumericEditField
        BandGapEnergyat0KEg0EditFieldLabel  matlab.ui.control.Label
        BandGapEnergyat0KEg0EditField   matlab.ui.control.NumericEditField
        EnergyGapParameteralphaEditFieldLabel  matlab.ui.control.Label
        EnergyGapParameteralphaEditField  matlab.ui.control.NumericEditField
        EnergyGapParameterbetaEditFieldLabel  matlab.ui.control.Label
        EnergyGapParameterbetaEditField  matlab.ui.control.NumericEditField
        SolarCellArrayLabel             matlab.ui.control.Label
        ArrayTypeButtonGroup            matlab.ui.container.ButtonGroup
        SeriesButton                    matlab.ui.control.RadioButton
        ParallelButton                  matlab.ui.control.RadioButton
        NumberofCellsLabel              matlab.ui.control.Label
        NumberofCellsEditField          matlab.ui.control.NumericEditField
        CellSizeLabel                   matlab.ui.control.Label
        CellSizeEditField               matlab.ui.control.NumericEditField
        ShuntResistanceLabel            matlab.ui.control.Label
        ShuntResistanceEditField        matlab.ui.control.NumericEditField
        SeriesResistanceLabel           matlab.ui.control.Label
        SeriesResistanceEditField       matlab.ui.control.NumericEditField
        SaturationCurrentTemperatureConstantsLabel  matlab.ui.control.Label
        Am2K3Label                      matlab.ui.control.Label
        eVLabel                         matlab.ui.control.Label
        eVK1Label                       matlab.ui.control.Label
        KLabel                          matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        ohmLabel                        matlab.ui.control.Label
        ohmLabel_2                      matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        ExternalQuantumEfficiencyLabel  matlab.ui.control.Label
        ExtQuantEffButtonGroup          matlab.ui.container.ButtonGroup
        UsedefaultvaluesButton          matlab.ui.control.RadioButton
        ChoosecustomfileButton          matlab.ui.control.RadioButton
        BrowseFilesButton_2             matlab.ui.control.Button
        FilenameEditFieldLabel_2        matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        OutputTab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        IndependentVarListBoxLabel      matlab.ui.control.Label
        IndependentVarListBox           matlab.ui.control.ListBox
        DependentVarListBoxLabel        matlab.ui.control.Label
        DependentVarListBox             matlab.ui.control.ListBox
        Label                           matlab.ui.control.Label
        PlotVariablesButton             matlab.ui.control.Button
        StartDateDatePickerLabel        matlab.ui.control.Label
        StartDateDatePicker             matlab.ui.control.DatePicker
        EndDateDatePickerLabel          matlab.ui.control.Label
        EndDateDatePicker               matlab.ui.control.DatePicker
        WaterQualityListBoxLabel        matlab.ui.control.Label
        WaterQualityListBox             matlab.ui.control.ListBox
        LoadDataButton                  matlab.ui.control.Button
        SaveasPDFButton                 matlab.ui.control.Button
        OpenFigureButton                matlab.ui.control.Button
        PlotTypeSwitchLabel             matlab.ui.control.Label
        PlotTypeSwitch                  matlab.ui.control.Switch
    end

    
    properties (Access = private)
        % Output variables:
        Day
        Month
        Year
        day_month
        base
        out_filepath
        data
        vars
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % Output variables:
            app.Day = [];
            app.Month = [];
            app.Year = [];
            app.day_month = [];
            app.base = '';
            app.out_filepath = '';
            app.data = struct([]);
            app.vars = {};
        end

        % Button pushed function: BrowseFilesButton
        function BrowseFilesButtonPushed(app, event)
            [files,path] = uigetfile({'*.xlsx;*.xlsx', 'Excel Files (*.xls, *.xlsx)';'*.csv','Comma Dilimiter (*.csv)';'*.mat','Matlab Files (*.mat)'});%,'Select One or More Files','MultiSelect', 'on');
            if files ~= 0
                app.EditField_Path.Value = string(path);
                app.OutputFolderEditField.Value = string(path);
%                 if class(files) == 'char'
                    C = strsplit(string(files),'.');
                    app.EditField_Filename.Value = string(C(1));
                    app.EditField_Ext.Value = string(C(2));
%                 else
%                     C = strsplit(string(files{1}),'.');
%                     files_list = string(C(1));
%                     app.EditField_Ext.Value = string(C(2));
%                     for i = 2:length(files)
%                         C = strsplit(string(files{i}),'.');
%                         new_file = string(C(1));
%                         files_list = strcat(files_list,',',new_file);
%                     end
%                     app.EditField_Filename.Value = files_list;
%                 end
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Selection changed function: MarineDeployment_ButtonGroup
        function MarineDeployment_ButtonGroupSelectionChanged(app, event)
            selectedButton = app.MarineDeployment_ButtonGroup.SelectedObject;
            if selectedButton == app.YesButton % If a marine deployment
                app.SelectWaterQuality_ListBox.Enable = 'on';
            else % Not a marine deployment
                app.SelectWaterQuality_ListBox.Value = {};
                app.SelectWaterQuality_ListBox.Enable = 'off';
            end
        end

        % Button pushed function: CompileResultsButton
        function CompileResultsButtonPushed(app, event)
        clc
        % Define Variables from GUI Inputs
        in_filepath = string(app.EditField_Path.Value);
        ext = string(app.EditField_Ext.Value);
        app.base = string(app.EditField_Filename.Value);
        file = strcat(in_filepath,'/',app.base,'.',ext);
        app.out_filepath = strcat(string(app.OutputFolderEditField.Value),'/Output');%_masterdriver');
        out_filetype = string(app.OutputFileTypeDropDown.Value);
        irr_filepath = string(app.FilePathEditField.Value);
        Water_Quality = single(str2double(app.SelectWaterQuality_ListBox.Value));
        Time_Zone = single(app.TimeZoneEditField.Value);
        if app.ArrayTypeButtonGroup.SelectedObject == app.SeriesButton
            array_type = 'series';
        else
            array_type = 'parallel';
        end
        array_n = single(app.NumberofCellsEditField.Value); %number of elements in the array
        Solar_Cell_Area = app.CellSizeEditField.Value; %units in m^2 (10mm x 6mm)
        Rsh = single(app.ShuntResistanceEditField.Value); %shunt resistence of cell; empirically derived in lab experiments [ohm]
        Rs = single(app.SeriesResistanceEditField.Value);   %series resistence of cell empirically derived from in lab experiments [ohm]
        C = single(app.CurrentdensityConstantCEditField.Value); %current density constant [A m^(-2) K^(-3)] 
        Eg_0 = single(app.BandGapEnergyat0KEg0EditField.Value); %[eV]
        alpha = single(app.EnergyGapParameteralphaEditField.Value); %[eV K^(-1)]
        beta = single(app.EnergyGapParameterbetaEditField.Value); %[K]
        outputNames = app.OutputVariablesListBox.Value; % User seleted output variables
        outputNames = [{'Time','Depth','T','Latitude','Longitude','Solar_Cell_Power_Output'},outputNames];
        % note: outputNames should include: 'Solar_Cell_Energy_Output' but code doesn' run with this included.
        % (output variables that don't need to be listed in MAIN but need to be available for plotting should go in line 209)
        Cust_Ext_Eff = string(app.FilenameEditField.Value);
        if Cust_Ext_Eff == ''
            Cust_Ext_Eff = 'default';
        end
        
        % Run Data format for selected file
        [app.Year, app.Month, app.Day] = Data_Format_Interpolation(file, app.base, ext, out_filetype); %pass the read in function the relevant information
        
        % % Continue with Master_Driver Code...
        Output_Driver(in_filepath, app.base, app.out_filepath, irr_filepath, outputNames,app.Day, Water_Quality, Time_Zone, array_type, array_n, Solar_Cell_Area, Rsh, Rs, C, Eg_0, alpha, beta,Cust_Ext_Eff)
        
        % % Switch to Results tab and add plot options
        app.TabGroup.SelectedTab = app.OutputTab;
        % Add output variables to plot options
        outputNames = [outputNames,'Solar_Cell_Energy_Output'];
        outputNames_full = Output_var2name(outputNames);
        app.IndependentVarListBox.Items = outputNames_full;
        app.DependentVarListBox.Items = outputNames_full;
        app.IndependentVarListBox.ItemsData = outputNames;
        app.DependentVarListBox.ItemsData = outputNames;
        % Add water qualities to plot options
        app.WaterQualityListBox.Items = string(Water_Quality);
        % Add start/end date to plot options
        app.day_month(1) = app.Day(1) - day(datetime(app.Year(1),app.Month(1),1),'dayofyear') + 1;
        app.day_month(2) = app.Day(end) - day(datetime(app.Year(end),app.Month(end),1),'dayofyear') + 1;
        app.StartDateDatePicker.Limits = [datetime(app.Year(1),app.Month(1), app.day_month(1)), datetime(app.Year(end),app.Month(end), app.day_month(2))];
        app.StartDateDatePicker.Value = datetime(app.Year(1),app.Month(1), app.day_month(1));
        app.EndDateDatePicker.Limits = [datetime(app.Year(1),app.Month(1), app.day_month(1)), datetime(app.Year(end),app.Month(end), app.day_month(2))];
        app.EndDateDatePicker.Value = datetime(app.Year(end),app.Month(end), app.day_month(2));
        % Enable Buttons
        app.LoadDataButton.Enable = 'on';
        app.PlotVariablesButton.Enable = 'on';
        app.PlotTypeSwitch.Enable = 'on';
        app.PlotTypeSwitchLabel.Enable = 'on';
        app.StartDateDatePicker.Enable = 'on';
        app.StartDateDatePickerLabel.Enable = 'on';
        app.EndDateDatePicker.Enable = 'on';
        app.EndDateDatePickerLabel.Enable = 'on';
        end

        % Button pushed function: BrowseFoldersButton
        function BrowseFoldersButtonPushed(app, event)
            current_path = app.EditField_Path.Value;
            path = uigetdir(current_path);
            if path ~= 0
                app.FilePathEditField.Value = string(path);
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: BrowseFoldersButton_2
        function BrowseFoldersButton_2Pushed(app, event)
            current_path = app.OutputFolderEditField.Value;
            path = uigetdir(current_path);
            if path ~= 0
                app.FilePathEditField.Value = string(path);
            end
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: PlotVariablesButton
        function PlotVariablesButtonPushed(app, event)
        % Find variables chosen    
        x = app.IndependentVarListBox.Value;
        if isfield(app.data,x) == 0
            warndlg('Please load data for desired water quality.')
        else
            y = app.DependentVarListBox.Value;
            app.vars = [x,y]; [vars_full,vars_full_units] = Output_var2name(app.vars);
    %         Water_Quality = app.WaterQualityListBox.Value;
            plottype = string(app.PlotTypeSwitch.Value);
            StartDay = app.StartDateDatePicker.Value;
            EndDay = app.EndDateDatePicker.Value;
            % Find data range to plot from selection
            Day_start = days(StartDay - datetime(app.Year(1),app.Month(1), app.day_month(1))) +1;
            Day_end = days(EndDay - datetime(app.Year(1),app.Month(1), app.day_month(1))) +1;
            % plot formatting pt. 1
            set(app.UIAxes,'FontSize',14);
            title(app.UIAxes,strcat(datestr(StartDay,'mm/dd/yy'),' - ',datestr(EndDay,'mm/dd/yy')),'Interpreter','latex')
            xlabel(app.UIAxes,vars_full_units{1},'Interpreter','latex')
            % Load files
    %         for iWQ = 1:length(Water_Quality)
    %             out_file = sprintf('%s/%s_WQ%sOutput.mat',app.out_filepath, app.base, string(Water_Quality));
    %             data = load(out_file, vars{:});
    %                 whos('-file',out_file)
                xx = app.data.(x);
                if string(x) == 'Time'
                    xx = xx(xx>=StartDay & xx<(EndDay+days(1)));
                else
                    xx = reshape(xx(:,Day_start:Day_end),1,[]);
                end
                if length(y) == 1 % plot single axis
                    yy = app.data.(string(y));
                    if string(y) == 'Time'
                        yy = yy(yy>=StartDay & yy<(EndDay+days(1)));
                    else
                        yy = reshape(yy(:,Day_start:Day_end),1,[]);
                    end
                    if plottype == 'Line'
                        plot(app.UIAxes,xx,yy)
                    else % scatter plot
                        scatter(app.UIAxes,xx,yy,5)
                    end
                    ylabel(app.UIAxes,vars_full_units{2},'Interpreter','latex')
                else % plot 2 axes
                    yy1 = app.data.(string(y{1})); yy2 = app.data.(string(y{2}));
                    if string(y{1}) == 'Time'
                        yy1 = yy1(yy1>=StartDay & yy1<(EndDay+days(1)));
                        yy2 = reshape(yy2(:,Day_start:Day_end),1,[]);
                    elseif string(y{2}) == 'Time'
                        yy2 = yy(yy>=StartDay & yy<(EndDay+days(1)));
                        yy1 = reshape(yy1(:,Day_start:Day_end),1,[]);
                    else
                        yy1 = reshape(yy1(:,Day_start:Day_end),1,[]);
                        yy2 = reshape(yy2(:,Day_start:Day_end),1,[]);
                    end
                    yyaxis(app.UIAxes,'left')
                        if plottype == 'Line'
                            plot(app.UIAxes,xx,yy1)
                        else % scatter plot
                            scatter(app.UIAxes,xx,yy1,5)
                        end
                        ylabel(app.UIAxes,vars_full_units{2},'Interpreter','latex')
                    yyaxis(app.UIAxes,'right')
                        if plottype == 'Line'
                            plot(app.UIAxes,xx,yy2)
                        else % scatter plot
                            scatter(app.UIAxes,xx,yy2,5)
                        end
                        ylabel(app.UIAxes,vars_full_units{3},'Interpreter','latex')
                    legend(app.UIAxes,vars_full{2:3})
                end
                hold(app.UIAxes,'off')
                % Enable buttons
                app.SaveasPDFButton.Enable = 'on';
                app.OpenFigureButton.Enable = 'on';
%         end
        end
        end

        % Value changed function: StartDateDatePicker
        function StartDateDatePickerValueChanged(app, event)
            startvalue = app.StartDateDatePicker.Value;
            endvalue = app.EndDateDatePicker.Value;
            app.EndDateDatePicker.Limits = [startvalue, endvalue];
        end

        % Value changed function: EndDateDatePicker
        function EndDateDatePickerValueChanged(app, event)
            endvalue = app.EndDateDatePicker.Value;
            startvalue = app.StartDateDatePicker.Value;
            app.StartDateDatePicker.Limits = [startvalue, endvalue];
        end

        % Value changed function: DependentVarListBox
        function DependentVarListBoxValueChanged(app, event)
            value = app.DependentVarListBox.Value;
            if length(value) > 2
                warndlg('Only two dependent variables can be plotted at once.')
                app.DependentVarListBox.Value = value(1:2);
            end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            Water_Quality = app.WaterQualityListBox.Value;
            out_file = sprintf('%s/%s_WQ%sOutput.mat',app.out_filepath, app.base, string(Water_Quality));
            data1 = load(out_file);%, vars{:});
            app.data = data1.out_WQ;
        end

        % Button pushed function: SaveasPDFButton
        function SaveasPDFButtonPushed(app, event)
            SaveName = strcat(filepath,'OutputPlot_',string(app.vars{1}),'_',string(app.vars{2}));
            if length(app.vars) >2
                SaveName = strcat(SaveName,'_',string(app.vars{3}));
            end
            fig = openfigwindow(app.UIAxes,'off');
            saveas(fig,SaveName,'pdf')
            delete(fig)
        end

        % Button pushed function: OpenFigureButton
        function OpenFigureButtonPushed(app, event)
            openfigwindow(app.UIAxes);
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.OutputVariablesListBox.Value = app.OutputVariablesListBox.ItemsData;
        end

        % Selection changed function: ExtQuantEffButtonGroup
        function ExtQuantEffButtonGroupSelectionChanged(app, event)
            selectedButton = app.ExtQuantEffButtonGroup.SelectedObject;
            if selectedButton == app.ChoosecustomfileButton
                app.FilenameEditField.Visible = 'on';
                app.FilenameEditFieldLabel_2.Visible = 'on';
                app.BrowseFilesButton_2.Visible = 'on';
            else % default
                app.FilenameEditField.Visible = 'off';
                app.FilenameEditFieldLabel_2.Visible = 'off';
                app.BrowseFilesButton_2.Visible = 'off';
                app.FilenameEditField.Value = '';
            end
        end

        % Button pushed function: BrowseFilesButton_2
        function BrowseFilesButton_2Pushed(app, event)
            [file,path] = uigetfile({'*.xlsx;*.xlsx', 'Excel Files (*.xls, *.xlsx)';'*.csv','Comma Dilimiter (*.csv)';'*.mat','Matlab Files (*.mat)'});%,'Select One or More Files','MultiSelect', 'on');
            if file ~= 0
                app.FilenameEditField.Value = strcat(string(path),'/',string(file));
            else
                app.FilenameEditField.Value = '';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.FilePathEditFieldLabel = uilabel(app.OptionsTab);
            app.FilePathEditFieldLabel.Tooltip = {'If formated as an .xlsx and or .csv file the input data should be compiled where each column represents:'; 'Time | Depth | Latitude | Longitude | Temperature | Ax | Ay | Az |'; 'If formatted as a mat file each variable named as follows:'; 'time | depth |   lat    |   lon     |    temp     | ax | ay | az |'; 'If a user does not have a certain variable the respective column should be left blank but the order/spacing should be maintained.'};
            app.FilePathEditFieldLabel.Text = 'File Path';
            app.EditField_Path = uieditfield(app.OptionsTab, 'text');
            app.EditField_Path.Tooltip = {'If formated as an .xlsx and or .csv file the input data should be compiled where each column represents:'; 'Time | Depth | Latitude | Longitude | Temperature | Ax | Ay | Az |'; 'If formatted as a mat file each variable named as follows:'; 'time | depth |   lat    |   lon     |    temp     | ax | ay | az |'; 'If a user does not have a certain variable the respective column should be left blank but the order/spacing should be maintained.'};
            app.FilenameEditFieldLabel = uilabel(app.OptionsTab);
            app.FilenameEditFieldLabel.Tooltip = {'If formated as an .xlsx and or .csv file the input data should be compiled where each column represents:'; 'Time | Depth | Latitude | Longitude | Temperature | Ax | Ay | Az |'; 'If formatted as a mat file each variable named as follows:'; 'time | depth |   lat    |   lon     |    temp     | ax | ay | az |'; 'If a user does not have a certain variable the respective column should be left blank but the order/spacing should be maintained.'};
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.EditField_Filename = uieditfield(app.OptionsTab, 'text');
            app.EditField_Filename.Tooltip = {'If formated as an .xlsx and or .csv file the input data should be compiled where each column represents:'; 'Time | Depth | Latitude | Longitude | Temperature | Ax | Ay | Az |'; 'If formatted as a mat file each variable named as follows:'; 'time | depth |   lat    |   lon     |    temp     | ax | ay | az |'; 'If a user does not have a certain variable the respective column should be left blank but the order/spacing should be maintained.'};
            app.ExtensionxlscsvmatLabel = uilabel(app.OptionsTab);
            app.ExtensionxlscsvmatLabel.Tooltip = {'If formated as an .xlsx and or .csv file the input data should be compiled where each column represents:'; 'Time | Depth | Latitude | Longitude | Temperature | Ax | Ay | Az |'; 'If formatted as a mat file each variable named as follows:'; 'time | depth |   lat    |   lon     |    temp     | ax | ay | az |'; 'If a user does not have a certain variable the respective column should be left blank but the order/spacing should be maintained.'};
            app.ExtensionxlscsvmatLabel.Text = 'Extension (xls, csv, mat)';
            app.EditField_Ext = uieditfield(app.OptionsTab, 'text');
            app.EditField_Ext.Tooltip = {'If formated as an .xlsx and or .csv file the input data should be compiled where each column represents:'; 'Time | Depth | Latitude | Longitude | Temperature | Ax | Ay | Az |'; 'If formatted as a mat file each variable named as follows:'; 'time | depth |   lat    |   lon     |    temp     | ax | ay | az |'; 'If a user does not have a certain variable the respective column should be left blank but the order/spacing should be maintained.'};
            app.FileLabel = uilabel(app.OptionsTab);
            app.FileLabel.Tooltip = {'If formated as an .xlsx and or .csv file the input data should be compiled where each column represents:'; 'Time | Depth | Latitude | Longitude | Temperature | Ax | Ay | Az |'; 'If formatted as a mat file each variable named as follows:'; 'time | depth |   lat    |   lon     |    temp     | ax | ay | az |'; 'If a user does not have a certain variable the respective column should be left blank but the order/spacing should be maintained.'};
            app.FileLabel.Text = 'File';
            app.BrowseFilesButton = uibutton(app.OptionsTab, 'push');
            app.BrowseFilesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFilesButtonPushed, true);
            app.BrowseFilesButton.Tooltip = {'If formated as an .xlsx and or .csv file the input data should be compiled where each column represents:'; 'Time | Depth | Latitude | Longitude | Temperature | Ax | Ay | Az |'; 'If formatted as a mat file each variable named as follows:'; 'time | depth |   lat    |   lon     |    temp     | ax | ay | az |'; 'If a user does not have a certain variable the respective column should be left blank but the order/spacing should be maintained.'};
            app.BrowseFilesButton.Text = 'Browse Files';
            app.WaterQualityLabel = uilabel(app.OptionsTab);
            app.WaterQualityLabel.Text = 'Water Quality';
            app.SelectWaterQualityOpenOcean15Coastal610Label = uilabel(app.OptionsTab);
            app.SelectWaterQualityOpenOcean15Coastal610Label.Tooltip = {'Select one or multiple (Hold Ctrl or Shift)'};
            app.SelectWaterQualityOpenOcean15Coastal610Label.Text = {'Select Water Quality:'; ' - Open Ocean (1-5)'; ' - Coastal (6-10)'};
            app.SelectWaterQuality_ListBox = uilistbox(app.OptionsTab);
            app.SelectWaterQuality_ListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.SelectWaterQuality_ListBox.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.SelectWaterQuality_ListBox.Multiselect = 'on';
            app.SelectWaterQuality_ListBox.Tooltip = {'Select one or multiple (Hold Ctrl or Shift)'};
            app.SelectWaterQuality_ListBox.Value = {};
            app.MarineDeployment_ButtonGroup = uibuttongroup(app.OptionsTab);
            app.MarineDeployment_ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MarineDeployment_ButtonGroupSelectionChanged, true);
            app.MarineDeployment_ButtonGroup.Title = 'Is this a submarine deployment?';
            app.YesButton = uiradiobutton(app.MarineDeployment_ButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.MarineDeployment_ButtonGroup);
            app.NoButton.Text = 'No';
            app.TimeZoneEditFieldLabel = uilabel(app.OptionsTab);
            app.TimeZoneEditFieldLabel.Text = 'Time Zone';
            app.TimeZoneEditField = uieditfield(app.OptionsTab, 'numeric');
            app.TimeZoneEditField.Tooltip = {'What time zone was the data collected in? (UTC + X)'};
            app.TimeZoneEditField.Value = -7;
            app.DesiredOutputVariablesLabel = uilabel(app.OptionsTab);
            app.DesiredOutputVariablesLabel.Text = 'Desired Output Variables';
            app.CompileResultsButton = uibutton(app.OptionsTab, 'push');
            app.CompileResultsButton.ButtonPushedFcn = createCallbackFcn(app, @CompileResultsButtonPushed, true);
            app.CompileResultsButton.Text = 'Compile Results';
            app.OutputVariablesListBox = uilistbox(app.OptionsTab);
            app.OutputVariablesListBox.Items = {'Panel Tilt', 'Panel Azimuth', 'Panel Zenith', 'Angle of Incidence', 'Global Horizantal Irradiance (Total)', 'Global Horizantal Irradiance (Spectrum)', 'Total Irradiance Incident to Cell', 'Open Current Voltage', 'Light Current'};
            app.OutputVariablesListBox.ItemsData = {'Panel_Tilt', 'Panel_Azimuth', 'Zenith', 'Incidence_Angle', 'Total_Scaled_GHI', 'Scaled_GHI_Spectrum', 'Total_Solar_Irradiance_Incident_To_Cell', 'V_oc', 'I_sc'};
            app.OutputVariablesListBox.Multiselect = 'on';
            app.OutputVariablesListBox.Tooltip = {'Select one or multiple (Hold Ctrl or Shift)'};
            app.OutputVariablesListBox.Value = {};
            app.CERESIrradianceDataFilePathLabel = uilabel(app.OptionsTab);
            app.CERESIrradianceDataFilePathLabel.Text = 'CERES Irradiance Data File Path ';
            app.BrowseFoldersButton = uibutton(app.OptionsTab, 'push');
            app.BrowseFoldersButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFoldersButtonPushed, true);
            app.BrowseFoldersButton.Text = 'Browse Folders';
            app.FilePathEditFieldLabel_2 = uilabel(app.OptionsTab);
            app.FilePathEditFieldLabel_2.Text = 'File Path';
            app.FilePathEditField = uieditfield(app.OptionsTab, 'text');
            app.OutputFilePathLabel = uilabel(app.OptionsTab);
            app.OutputFilePathLabel.Text = 'Output File Path';
            app.BrowseFoldersButton_2 = uibutton(app.OptionsTab, 'push');
            app.BrowseFoldersButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseFoldersButton_2Pushed, true);
            app.BrowseFoldersButton_2.Text = 'Browse Folders';
            app.OutputFolderEditFieldLabel = uilabel(app.OptionsTab);
            app.OutputFolderEditFieldLabel.Text = 'Output Folder';
            app.OutputFolderEditField = uieditfield(app.OptionsTab, 'text');
            app.OutputFolderEditField.Tooltip = {'A new folder called "Output" will be created in this directory containing all results.'};
            app.OutputFileTypematorcsvLabel = uilabel(app.OptionsTab);
            app.OutputFileTypematorcsvLabel.Enable = 'off';
            app.OutputFileTypematorcsvLabel.Text = 'Output File Type (mat or csv)';
            app.OutputFileTypeDropDown = uidropdown(app.OptionsTab);
            app.OutputFileTypeDropDown.Items = {'mat', 'csv'};
            app.OutputFileTypeDropDown.Enable = 'off';
            app.OutputFileTypeDropDown.Value = 'mat';
            app.SelectAllButton = uibutton(app.OptionsTab, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.AutomaticOutputVariablesTimeLabel = uilabel(app.OptionsTab);
            app.AutomaticOutputVariablesTimeLabel.Text = {'Automatic output variables:'; ' - Time'; ' - Depth'; ' - Temperature'; ' - Latitude '; ' - Longitude'; ' - Max Energy'; ' - Max Power'};
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.CurrentdensityConstantCEditFieldLabel = uilabel(app.AdvancedTab);
            app.CurrentdensityConstantCEditFieldLabel.Enable = 'off';
            app.CurrentdensityConstantCEditFieldLabel.Text = 'Current density Constant, C';
            app.CurrentdensityConstantCEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.CurrentdensityConstantCEditField.Enable = 'off';
            app.CurrentdensityConstantCEditField.Value = 500;
            app.BandGapEnergyat0KEg0EditFieldLabel = uilabel(app.AdvancedTab);
            app.BandGapEnergyat0KEg0EditFieldLabel.Enable = 'off';
            app.BandGapEnergyat0KEg0EditFieldLabel.Text = 'Band Gap Energy at 0K, Eg0';
            app.BandGapEnergyat0KEg0EditField = uieditfield(app.AdvancedTab, 'numeric');
            app.BandGapEnergyat0KEg0EditField.Enable = 'off';
            app.BandGapEnergyat0KEg0EditField.Value = 1.1557;
            app.EnergyGapParameteralphaEditFieldLabel = uilabel(app.AdvancedTab);
            app.EnergyGapParameteralphaEditFieldLabel.Enable = 'off';
            app.EnergyGapParameteralphaEditFieldLabel.Text = 'Energy Gap Parameter, alpha';
            app.EnergyGapParameteralphaEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.EnergyGapParameteralphaEditField.Enable = 'off';
            app.EnergyGapParameteralphaEditField.Value = 0.0007021;
            app.EnergyGapParameterbetaEditFieldLabel = uilabel(app.AdvancedTab);
            app.EnergyGapParameterbetaEditFieldLabel.Enable = 'off';
            app.EnergyGapParameterbetaEditFieldLabel.Text = 'Energy Gap Parameter, beta';
            app.EnergyGapParameterbetaEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.EnergyGapParameterbetaEditField.Enable = 'off';
            app.EnergyGapParameterbetaEditField.Value = 1108;
            app.SolarCellArrayLabel = uilabel(app.AdvancedTab);
            app.SolarCellArrayLabel.Text = 'Solar Cell Array';
            app.ArrayTypeButtonGroup = uibuttongroup(app.AdvancedTab);
            app.ArrayTypeButtonGroup.Title = 'Array Type';
            app.SeriesButton = uiradiobutton(app.ArrayTypeButtonGroup);
            app.SeriesButton.Text = 'Series';
            app.SeriesButton.Value = true;
            app.ParallelButton = uiradiobutton(app.ArrayTypeButtonGroup);
            app.ParallelButton.Text = 'Parallel';
            app.NumberofCellsLabel = uilabel(app.AdvancedTab);
            app.NumberofCellsLabel.Text = 'Number of Cells';
            app.NumberofCellsEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.NumberofCellsEditField.Tooltip = {'Number of cells in the array'};
            app.NumberofCellsEditField.Value = 10;
            app.CellSizeLabel = uilabel(app.AdvancedTab);
            app.CellSizeLabel.Text = 'Cell Size';
            app.CellSizeEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.CellSizeEditField.Tooltip = {'Solar cell area'};
            app.CellSizeEditField.Value = 6e-05;
            app.ShuntResistanceLabel = uilabel(app.AdvancedTab);
            app.ShuntResistanceLabel.Text = 'Shunt Resistance';
            app.ShuntResistanceEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.ShuntResistanceEditField.Tooltip = {'Shunt resistence of cell'; ' empirically derived in lab experiments.'};
            app.ShuntResistanceEditField.Value = 50000;
            app.SeriesResistanceLabel = uilabel(app.AdvancedTab);
            app.SeriesResistanceLabel.Text = 'Series Resistance';
            app.SeriesResistanceEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.SeriesResistanceEditField.Tooltip = {'Series resistence of cell'; ' empirically derived from in lab experiments.'};
            app.SeriesResistanceEditField.Value = 4.82;
            app.SaturationCurrentTemperatureConstantsLabel = uilabel(app.AdvancedTab);
            app.SaturationCurrentTemperatureConstantsLabel.Enable = 'off';
            app.SaturationCurrentTemperatureConstantsLabel.Text = 'Saturation Current Temperature Constants';
            app.Am2K3Label = uilabel(app.AdvancedTab);
            app.Am2K3Label.Enable = 'off';
            app.Am2K3Label.Text = '[A m^(-2) K^(-3)]';
            app.eVLabel = uilabel(app.AdvancedTab);
            app.eVLabel.Enable = 'off';
            app.eVLabel.Text = '[eV]';
            app.eVK1Label = uilabel(app.AdvancedTab);
            app.eVK1Label.Enable = 'off';
            app.eVK1Label.Text = '[eV K^(-1)]';
            app.KLabel = uilabel(app.AdvancedTab);
            app.KLabel.Enable = 'off';
            app.KLabel.Text = '[K]';
            app.m2Label = uilabel(app.AdvancedTab);
            app.m2Label.Text = '[m^2]';
            app.ohmLabel = uilabel(app.AdvancedTab);
            app.ohmLabel.Text = '[ohm]';
            app.ohmLabel_2 = uilabel(app.AdvancedTab);
            app.ohmLabel_2.Text = '[ohm]';
            app.Label_3 = uilabel(app.AdvancedTab);
            app.Label_3.Text = '';
            app.ExternalQuantumEfficiencyLabel = uilabel(app.AdvancedTab);
            app.ExternalQuantumEfficiencyLabel.Text = 'External Quantum Efficiency';
            app.ExtQuantEffButtonGroup = uibuttongroup(app.AdvancedTab);
            app.ExtQuantEffButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExtQuantEffButtonGroupSelectionChanged, true);
            app.UsedefaultvaluesButton = uiradiobutton(app.ExtQuantEffButtonGroup);
            app.UsedefaultvaluesButton.Text = 'Use default values';
            app.UsedefaultvaluesButton.Value = true;
            app.ChoosecustomfileButton = uiradiobutton(app.ExtQuantEffButtonGroup);
            app.ChoosecustomfileButton.Text = 'Choose custom file';
            app.BrowseFilesButton_2 = uibutton(app.AdvancedTab, 'push');
            app.BrowseFilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseFilesButton_2Pushed, true);
            app.BrowseFilesButton_2.Visible = 'off';
            app.BrowseFilesButton_2.Text = 'Browse Files';
            app.FilenameEditFieldLabel_2 = uilabel(app.AdvancedTab);
            app.FilenameEditFieldLabel_2.Visible = 'off';
            app.FilenameEditFieldLabel_2.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.AdvancedTab, 'text');
            app.FilenameEditField.Visible = 'off';
            app.FilenameEditField.Tooltip = {'Filename'; ' including file path and extension.'};
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.UIAxes = uiaxes(app.OutputTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.IndependentVarListBoxLabel = uilabel(app.OutputTab);
            app.IndependentVarListBoxLabel.Text = 'Independent Var';
            app.IndependentVarListBox = uilistbox(app.OutputTab);
            app.IndependentVarListBox.Items = {};
            app.IndependentVarListBox.Value = {};
            app.DependentVarListBoxLabel = uilabel(app.OutputTab);
            app.DependentVarListBoxLabel.Text = 'Dependent Var';
            app.DependentVarListBox = uilistbox(app.OutputTab);
            app.DependentVarListBox.Items = {};
            app.DependentVarListBox.Multiselect = 'on';
            app.DependentVarListBox.ValueChangedFcn = createCallbackFcn(app, @DependentVarListBoxValueChanged, true);
            app.DependentVarListBox.Tooltip = {'Choose up to 2 output variables to plot on the y axis (Hold Ctrl or Shift for multiple selections)'};
            app.DependentVarListBox.Value = {};
            app.Label = uilabel(app.OutputTab);
            app.Label.Text = 'Results (note: all variables are saved in the specified folder path in "Output")';
            app.PlotVariablesButton = uibutton(app.OutputTab, 'push');
            app.PlotVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotVariablesButtonPushed, true);
            app.PlotVariablesButton.Enable = 'off';
            app.PlotVariablesButton.Text = 'Plot Variables';
            app.StartDateDatePickerLabel = uilabel(app.OutputTab);
            app.StartDateDatePickerLabel.Enable = 'off';
            app.StartDateDatePickerLabel.Tooltip = {'By default'; ' the entire date range available is selected.  For a more precise range'; ' select the desired start and end dates.'};
            app.StartDateDatePickerLabel.Text = 'Start Date';
            app.StartDateDatePicker = uidatepicker(app.OutputTab);
            app.StartDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @StartDateDatePickerValueChanged, true);
            app.StartDateDatePicker.Enable = 'off';
            app.StartDateDatePicker.Tooltip = {'By default'; ' the entire date range available is selected.  For a more precise range'; ' select the desired start and end dates.'};
            app.EndDateDatePickerLabel = uilabel(app.OutputTab);
            app.EndDateDatePickerLabel.Enable = 'off';
            app.EndDateDatePickerLabel.Tooltip = {'By default'; ' the entire date range available is selected.  For a more precise range'; ' select the desired start and end dates.'};
            app.EndDateDatePickerLabel.Text = 'End Date';
            app.EndDateDatePicker = uidatepicker(app.OutputTab);
            app.EndDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @EndDateDatePickerValueChanged, true);
            app.EndDateDatePicker.Enable = 'off';
            app.EndDateDatePicker.Tooltip = {'By default'; ' the entire date range available is selected.  For a more precise range'; ' select the desired start and end dates.'};
            app.WaterQualityListBoxLabel = uilabel(app.OutputTab);
            app.WaterQualityListBoxLabel.Text = 'Water Quality';
            app.WaterQualityListBox = uilistbox(app.OutputTab);
            app.WaterQualityListBox.Items = {};
            app.WaterQualityListBox.Tooltip = {''};
            app.WaterQualityListBox.Value = {};
            app.LoadDataButton = uibutton(app.OutputTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Enable = 'off';
            app.LoadDataButton.Text = 'Load Data';
            app.SaveasPDFButton = uibutton(app.OutputTab, 'push');
            app.SaveasPDFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasPDFButtonPushed, true);
            app.SaveasPDFButton.Enable = 'off';
            app.SaveasPDFButton.Text = 'Save as PDF';
            app.OpenFigureButton = uibutton(app.OutputTab, 'push');
            app.OpenFigureButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFigureButtonPushed, true);
            app.OpenFigureButton.Enable = 'off';
            app.OpenFigureButton.Text = 'Open Figure';
            app.PlotTypeSwitchLabel = uilabel(app.OutputTab);
            app.PlotTypeSwitchLabel.Enable = 'off';
            app.PlotTypeSwitchLabel.Text = 'Plot Type';
            app.PlotTypeSwitch = uiswitch(app.OutputTab, 'slider');
            app.PlotTypeSwitch.Items = {'Scatter', 'Line'};
            app.PlotTypeSwitch.Enable = 'off';
            app.PlotTypeSwitch.Value = 'Scatter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SAM_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end